classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        ResumeButton               matlab.ui.control.Button
        HideShowSafetyGlassButton  matlab.ui.control.Button
        plant5Button               matlab.ui.control.Button
        plant4Button               matlab.ui.control.Button
        plant3Button               matlab.ui.control.Button
        plant2Button               matlab.ui.control.Button
        plant1Button               matlab.ui.control.Button
        plantAllButton             matlab.ui.control.Button
        water5Button               matlab.ui.control.Button
        water4Button               matlab.ui.control.Button
        water3Button               matlab.ui.control.Button
        water2Button               matlab.ui.control.Button
        water1Button               matlab.ui.control.Button
        waterAllButton             matlab.ui.control.Button
        PlantLabel                 matlab.ui.control.Label
        AllButton                  matlab.ui.control.Button
        WaterLabel                 matlab.ui.control.Label
        EStopButton                matlab.ui.control.Button
        GardeningRobotLabel        matlab.ui.control.Label
        plot                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here

        estopActive = false;
        table
        firstAid
        estopObject
        seedBowl
        gardenBed
        glass = true;
        glassPanel1
        glassPanel2
        glassPanel3
        glassPanel4
        glassPanel5
        robot1
        robot2
        can
        shovel
        angleDown = 7*pi/6;
        angleUp = 5*pi/6;
        steps = 100;
        currentPosABB
        currentPosDobot
        shovelVertices
        canVertices
        arduinoBoard
    end
    
    methods (Access = private)
        function move2PosDobot(app, robot1, startPosDobot, endPosDobot, steps, canVertices, can)
            qMatrix = jtraj(startPosDobot, endPosDobot, steps);

            for i = 1:steps
                if app.estopActive
                    break;
                end

                robot1.model.animate(qMatrix(i,:));
                tr = robot1.model.fkine(qMatrix(i,:));
                transformedCanVertices = [canVertices, ones(size(canVertices, 1),1)]*tr.T';
                set(can, 'Vertices', transformedCanVertices(:, 1:3));
                axis equal
                drawnow()
            end
        end

        function move2PosABB(app, robot2, startPosABB, endPosABB, steps, shovelVertices, shovel)
            qMatrix = jtraj(startPosABB, endPosABB, steps);

            for i = 1:steps
                robot2.model.animate(qMatrix(i,:));
                tr = robot2.model.fkine(qMatrix(i,:));
                transformedVertices = [shovelVertices, ones(size(shovelVertices, 1),1)]*tr.T';
                set(shovel, 'Vertices', transformedVertices(:, 1:3));
                axis equal
                drawnow()
             end
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            [app.table, app.firstAid, app.estopObject, app.glassPanel1, app.glassPanel2, app.glassPanel3, app.glassPanel4, app.glassPanel5, app.gardenBed, app.seedBowl] = makeEnvironment();
            
            % set([app.glassPanel1, app.glassPanel2, app.glassPanel3, app.glassPanel4, app.glassPanel5], 'Visible', 'off');

            % Dobot Magician Setup
            app.robot1 = DobotMagician;
            offsetDobot = transl(0.5, 0.25, 0.15)*trotz(pi/2);
            app.robot1.model.base = offsetDobot;
            
            app.currentPosDobot = app.robot1.model.getpos();
            hold on

            % ABB IRB1100 Setup
            app.robot2 = IRB1100;
            offsetABB = transl(0.5, 0, 0)*trotz(-pi/2);
            app.robot2.model.base = offsetABB;

            app.currentPosABB = app.robot2.model.getpos();
            drawnow();

            app.can = PlaceObject('wateringcan.ply');
            app.canVertices = get(app.can, 'Vertices');
            transformedCanVertices = [app.canVertices, ...
                ones(size(app.canVertices, 1), 1)]*transl(0, -0.25, 1)';
            set(app.can, 'Vertices', transformedCanVertices(:, 1:3));
            
            app.shovel = PlaceObject('shovel.ply');
            app.shovelVertices = get(app.shovel, 'Vertices');
            transformedVertices = [app.shovelVertices, ...
                ones(size(app.shovelVertices, 1), 1)]*transl(0, -0.25, 1)';
            set(app.shovel, 'Vertices', transformedVertices(:, 1:3));
            
            T0_ABB = transl(0, 0, 0);
            q0_ABB = app.robot2.model.ikcon(T0_ABB, app.currentPosABB);
            
            app.move2PosABB(app.robot2, app.currentPosABB, app.currentPosABB, app.steps, app.shovelVertices, app.shovel);
            app.currentPosABB = app.robot2.model.getpos();
            
            T0_Dobot = transl(0, 0, 0);
            q0_Dobot = app.robot1.model.ikunc(T0_Dobot, app.currentPosDobot);
            
            app.move2PosDobot(app.robot1, app.currentPosDobot, app.currentPosDobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            q0 = app.currentPosDobot;

            % Move to Can and pick it up
            T1_Dobot = transl(0.22 ,0 , 0.25);
            T2_Dobot = transl(0.22, 0, 0.05);
            T3_Dobot = transl(0.22, 0 , 0.25);
            
            q1_Dobot = app.robot1.model.ikunc(T1_Dobot, app.currentPosDobot);
            q2_Dobot = app.robot1.model.ikunc(T2_Dobot, app.currentPosDobot);
            q3_Dobot = app.robot1.model.ikunc(T3_Dobot, app.currentPosDobot);

            app.move2PosDobot(app.robot1, app.currentPosDobot, q1_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();

            app.move2PosDobot(app.robot1, app.currentPosDobot, q2_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1, app.currentPosDobot, q3_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
        end

        % Button pushed function: HideShowSafetyGlassButton
        function hideSafetyGlass(app, event)
            if (app.glass == true)
                set([app.glassPanel1, app.glassPanel2, app.glassPanel3, app.glassPanel4, app.glassPanel5], 'Visible', 'off');
                app.glass = false;
            else
                set([app.glassPanel1, app.glassPanel2, app.glassPanel3, app.glassPanel4, app.glassPanel5], 'Visible', 'on');
                app.glass = true;
            end
        end

        % Button pushed function: water1Button
        function waterPlot1(app, event)
            % Move to 1st plot
            [T4_Dobot, T5_Dobot, T6_Dobot] = plot1Dobot(1);
            
            q4_Dobot = app.robot1.model.ikunc(T4_Dobot, app.currentPosDobot);
            q5_Dobot = app.robot1.model.ikunc(T5_Dobot, app.currentPosDobot);
            q6_Dobot = app.robot1.model.ikunc(T6_Dobot, app.currentPosDobot);

            app.move2PosDobot(app.robot1, app.currentPosDobot, q4_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1, app.currentPosDobot, q5_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1, app.currentPosDobot, q6_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
        end

        % Button pushed function: water2Button
        function waterPlot2(app, event)
            % Move to 2nd plot
            [T7_Dobot, T8_Dobot, T9_Dobot] = plot2Dobot(1);
            
            q7_Dobot = app.robot1.model.ikunc(T7_Dobot, app.currentPosDobot);
            q8_Dobot = app.robot1.model.ikunc(T8_Dobot, app.currentPosDobot);
            q9_Dobot = app.robot1.model.ikunc(T9_Dobot, app.currentPosDobot);

            app.move2PosDobot(app.robot1, app.currentPosDobot, q7_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1, app.currentPosDobot, q8_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q9_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
        end

        % Button pushed function: water3Button
        function waterPlot3(app, event)
            % Move to 3rd plot
            [T10_Dobot, T11_Dobot, T12_Dobot] = plot3Dobot(1);  
            
            q10_Dobot = app.robot1.model.ikunc(T10_Dobot,app.currentPosDobot);
            q11_Dobot = app.robot1.model.ikunc(T11_Dobot,app.currentPosDobot);
            q12_Dobot = app.robot1.model.ikunc(T12_Dobot,app.currentPosDobot);

            app.move2PosDobot(app.robot1,app.currentPosDobot, q10_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q11_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q12_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
        end

        % Button pushed function: water4Button
        function waterPlot4(app, event)
            [T13_Dobot, T14_Dobot, T15_Dobot] = plot4Dobot(1) ;

            q13_Dobot = app.robot1.model.ikunc(T13_Dobot,app.currentPosDobot);
            q14_Dobot = app.robot1.model.ikunc(T14_Dobot,app.currentPosDobot);
            q15_Dobot = app.robot1.model.ikunc(T15_Dobot,app.currentPosDobot);

            app.move2PosDobot(app.robot1,app.currentPosDobot, q13_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q14_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q15_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
        end

        % Button pushed function: water5Button
        function waterPlot5(app, event)
            [T16_Dobot, T17_Dobot, T18_Dobot] = plot5Dobot(1);

            q16_Dobot = app.robot1.model.ikunc(T16_Dobot,app.currentPosDobot);
            q17_Dobot = app.robot1.model.ikunc(T17_Dobot,app.currentPosDobot);
            q18_Dobot = app.robot1.model.ikunc(T18_Dobot,app.currentPosDobot);

            app.move2PosDobot(app.robot1,app.currentPosDobot, q16_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q17_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
            
            app.move2PosDobot(app.robot1,app.currentPosDobot, q18_Dobot, app.steps, app.canVertices, app.can);
            app.currentPosDobot = app.robot1.model.getpos();
        end

        % Button pushed function: waterAllButton
        function waterAll(app, event)
            app.waterPlot1();
            app.waterPlot2();
            app.waterPlot3();
            app.waterPlot4();
            app.waterPlot5();
        end

        % Button pushed function: plant1Button
        function plantPlot1(app, event)
            
        end

        % Button pushed function: plant2Button
        function plantPlot2(app, event)
            
        end

        % Button pushed function: plant3Button
        function plantPlot3(app, event)
            
        end

        % Button pushed function: plant4Button
        function plantPlot4(app, event)
            
        end

        % Button pushed function: plant5Button
        function plantPlot5(app, event)
            
        end

        % Button pushed function: plantAllButton
        function plantAll(app, event)
            app.plantPlot1();
            app.plantPlot2();
            app.plantPlot3();
            app.plantPlot4();
            app.plantPlot5();
        end

        % Button pushed function: EStopButton
        function estop(app, event)
            app.estopActive = true;
            allButtons = [app.EStopButton, app.HideShowSafetyGlassButton, app.plant1Button, app.plant2Button, app.plant3Button, app.plant4Button, app.plant5Button, app.plantAllButton, app.water1Button, app.water2Button, app.water3Button, app.water4Button, app.water5Button, app.waterAllButton, app.AllButton, app.WaterLabel, app.PlantLabel];
            set(allButtons, 'Visible', 'off', 'Enable', 'off');

            set(app.ResumeButton, 'Visible', 'on', 'Enable', 'on');
        end

        % Button pushed function: ResumeButton
        function resume(app, event)
            allButtons = [app.EStopButton, app.HideShowSafetyGlassButton, app.plant1Button, app.plant2Button, app.plant3Button, app.plant4Button, app.plant5Button, app.plantAllButton, app.water1Button, app.water2Button, app.water3Button, app.water4Button, app.water5Button, app.waterAllButton, app.AllButton, app.WaterLabel, app.PlantLabel];
            set(allButtons, 'Visible', 'on', 'Enable', 'on');
                
            set(app.ResumeButton, 'Visible', 'off', 'Enable', 'off');
            app.estopActive = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Gardening Robot';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.GardeningRobotLabel = uilabel(app.figure1);
            app.GardeningRobotLabel.Text = 'Gardening Robot';
            app.EStopButton = uibutton(app.figure1, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @estop, true);
            app.EStopButton.Text = 'E-Stop';
            app.WaterLabel = uilabel(app.figure1);
            app.WaterLabel.Text = 'Water';
            app.AllButton = uibutton(app.figure1, 'push');
            app.AllButton.Text = 'All';
            app.PlantLabel = uilabel(app.figure1);
            app.PlantLabel.Text = 'Plant';
            app.waterAllButton = uibutton(app.figure1, 'push');
            app.waterAllButton.ButtonPushedFcn = createCallbackFcn(app, @waterAll, true);
            app.waterAllButton.Tag = 'WaterAllButton';
            app.waterAllButton.Text = 'All';
            app.water1Button = uibutton(app.figure1, 'push');
            app.water1Button.ButtonPushedFcn = createCallbackFcn(app, @waterPlot1, true);
            app.water1Button.Text = '1';
            app.water2Button = uibutton(app.figure1, 'push');
            app.water2Button.ButtonPushedFcn = createCallbackFcn(app, @waterPlot2, true);
            app.water2Button.Text = '2';
            app.water3Button = uibutton(app.figure1, 'push');
            app.water3Button.ButtonPushedFcn = createCallbackFcn(app, @waterPlot3, true);
            app.water3Button.Text = '3';
            app.water4Button = uibutton(app.figure1, 'push');
            app.water4Button.ButtonPushedFcn = createCallbackFcn(app, @waterPlot4, true);
            app.water4Button.Text = '4';
            app.water5Button = uibutton(app.figure1, 'push');
            app.water5Button.ButtonPushedFcn = createCallbackFcn(app, @waterPlot5, true);
            app.water5Button.Text = '5';
            app.plantAllButton = uibutton(app.figure1, 'push');
            app.plantAllButton.ButtonPushedFcn = createCallbackFcn(app, @plantAll, true);
            app.plantAllButton.Text = 'All';
            app.plant1Button = uibutton(app.figure1, 'push');
            app.plant1Button.ButtonPushedFcn = createCallbackFcn(app, @plantPlot1, true);
            app.plant1Button.Text = '1';
            app.plant2Button = uibutton(app.figure1, 'push');
            app.plant2Button.ButtonPushedFcn = createCallbackFcn(app, @plantPlot2, true);
            app.plant2Button.Text = '2';
            app.plant3Button = uibutton(app.figure1, 'push');
            app.plant3Button.ButtonPushedFcn = createCallbackFcn(app, @plantPlot3, true);
            app.plant3Button.Text = '3';
            app.plant4Button = uibutton(app.figure1, 'push');
            app.plant4Button.ButtonPushedFcn = createCallbackFcn(app, @plantPlot4, true);
            app.plant4Button.Text = '4';
            app.plant5Button = uibutton(app.figure1, 'push');
            app.plant5Button.ButtonPushedFcn = createCallbackFcn(app, @plantPlot5, true);
            app.plant5Button.Text = '5';
            app.HideShowSafetyGlassButton = uibutton(app.figure1, 'push');
            app.HideShowSafetyGlassButton.ButtonPushedFcn = createCallbackFcn(app, @hideSafetyGlass, true);
            app.HideShowSafetyGlassButton.Text = {'Hide / Show'; ' Safety Glass'};
            app.ResumeButton = uibutton(app.figure1, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @resume, true);
            app.ResumeButton.Enable = 'off';
            app.ResumeButton.Visible = 'off';
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end